classdef page1beta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        MainTab                       matlab.ui.container.Tab
        BrowseButton                  matlab.ui.control.Button
        NoiseButtonGroup              matlab.ui.container.ButtonGroup
        GaussianButton                matlab.ui.control.RadioButton
        SpeckleButton                 matlab.ui.control.RadioButton
        SaltPepperButton              matlab.ui.control.RadioButton
        PoissonButton                 matlab.ui.control.RadioButton
        RemoveNoiseButton             matlab.ui.control.Button
        AddNoiseButton                matlab.ui.control.Button
        OriginalButton                matlab.ui.control.Button
        UIMain                        matlab.ui.control.UIAxes
        JUMPButton                    matlab.ui.control.Button
        NoiseAdjust                   matlab.ui.control.Slider
        GaugeText                     matlab.ui.control.NumericEditField
        Label1                        matlab.ui.control.NumericEditField
        MSE                           matlab.ui.control.Label
        SNRLabel_2                    matlab.ui.control.Label
        SNR1                          matlab.ui.control.NumericEditField
        SmoothTab                     matlab.ui.container.Tab
        BackButton                    matlab.ui.control.Button
        SmootheningFilterButtonGroup  matlab.ui.container.ButtonGroup
        GaussianButton_2              matlab.ui.control.RadioButton
        AverageButton_2               matlab.ui.control.RadioButton
        MedianButton_2                matlab.ui.control.RadioButton
        EdgePreservingButton_2        matlab.ui.control.RadioButton
        ApplyFilterButton             matlab.ui.control.Button
        HistogramEquivalenceButton    matlab.ui.control.Button
        UISmooth                      matlab.ui.control.UIAxes
        SkipSmooth                    matlab.ui.control.Button
        Knob                          matlab.ui.control.DiscreteKnob
        Sigma                         matlab.ui.control.Knob
        MSELabel                      matlab.ui.control.Label
        Label2                        matlab.ui.control.NumericEditField
        SNRLabel_3                    matlab.ui.control.Label
        SNR2                          matlab.ui.control.NumericEditField
        HistogramTab                  matlab.ui.container.Tab
        BackButton_2                  matlab.ui.control.Button
        SharpenButton                 matlab.ui.control.Button
        IncreaseContrastButton        matlab.ui.control.Button
        ButtonGroup                   matlab.ui.container.ButtonGroup
        RGBButton                     matlab.ui.control.RadioButton
        BWButton                      matlab.ui.control.RadioButton
        UIHistogram                   matlab.ui.control.UIAxes
        DecreaseContrastButton        matlab.ui.control.Button
        MSELabel_2                    matlab.ui.control.Label
        Label3                        matlab.ui.control.NumericEditField
        error                         matlab.ui.control.Label
        SNRLabel                      matlab.ui.control.Label
        SNR3                          matlab.ui.control.NumericEditField
        SharpenTab                    matlab.ui.container.Tab
        BackButton_3                  matlab.ui.control.Button
        Sharpen4                      matlab.ui.control.Button
        ExtraButton                   matlab.ui.control.Button
        UISharpen                     matlab.ui.control.UIAxes
        SNR_rgb                       matlab.ui.control.Label
        Label4                        matlab.ui.control.NumericEditField
        Label5                        matlab.ui.control.NumericEditField
        Sharpen5                      matlab.ui.control.Button
        ExtraTab                      matlab.ui.container.Tab
        ExtraButtonGroup              matlab.ui.container.ButtonGroup
        VintageButton                 matlab.ui.control.RadioButton
        BinaryButton                  matlab.ui.control.RadioButton
        SobelButton                   matlab.ui.control.RadioButton
        CannyButton                   matlab.ui.control.RadioButton
        PrewittButtonBW               matlab.ui.control.RadioButton
        CannyButtonBW                 matlab.ui.control.RadioButton
        SobelButtonBW                 matlab.ui.control.RadioButton
        RobertsButtonBW               matlab.ui.control.RadioButton
        RobertsButton                 matlab.ui.control.RadioButton
        ApplyButton                   matlab.ui.control.Button
        BackButton_4                  matlab.ui.control.Button
        SkipButton_Extra              matlab.ui.control.Button
        ContinueButton                matlab.ui.control.Button
        UIExtra                       matlab.ui.control.UIAxes
        Slider                        matlab.ui.control.Slider
        OtsuButton                    matlab.ui.control.Button
        EditField                     matlab.ui.control.NumericEditField
        FacesTab                      matlab.ui.container.Tab
        UIFaces                       matlab.ui.control.UIAxes
        DetectButton                  matlab.ui.control.Button
        BackButton_5                  matlab.ui.control.Button
        NumberofFacesEditFieldLabel   matlab.ui.control.Label
        NumberofFacesEditField        matlab.ui.control.NumericEditField
        message                       matlab.ui.control.TextArea
        Proceed                       matlab.ui.control.Button
        WinTab                        matlab.ui.container.Tab
        Message                       matlab.ui.control.Label
        HitMeButton                   matlab.ui.control.Button
        Switch                        matlab.ui.control.Switch
    end


    methods (Access = public)
    
        function results = func(app)
            
        end
        
    end
    

    methods (Access = private)

        % Button pushed function: AddNoiseButton
        function AddNoiseButtonPushed(app, event)
            app.RemoveNoiseButton.Enable='on';
            app.NoiseAdjust.Value=app.GaugeText.Value ;
            app.GaugeText.Enable='on';
            global k;
            global k_gaussian;
            global k_speckle;
            global k_saltnpepper;
            
            option=app.NoiseButtonGroup.SelectedObject;
            value=option.Text;
            
            app.OriginalButton.Visible='on';
            switch value
                case 'Gaussian'
                    imshow(imnoise(k,'gaussian'),'Parent',app.UIMain);
                    imshow(imnoise(k,'gaussian'),'Parent',app.UISmooth);
                    k_gaussian=imnoise(k,'gaussian');
                    app.NoiseAdjust.Enable='on';
                    
                    
                case 'Speckle'
                    imshow(imnoise(k,'speckle'),'Parent',app.UIMain);
                    imshow(imnoise(k,'speckle'),'Parent',app.UISmooth);
                    k_speckle=imnoise(k,'speckle');
                    app.NoiseAdjust.Enable='on';
                    
                    
                case 'Salt-Pepper'
                    
                    imshow(imnoise(k,'salt & pepper'),'Parent',app.UIMain);
                    imshow(imnoise(k,'salt & pepper'),'Parent',app.UISmooth);
                    k_saltnpepper=imnoise(k,'salt & pepper');
                    app.NoiseAdjust.Enable='on';
                    
                case 'Poisson'
                    
                    imshow(imnoise(k,'poisson'),'Parent',app.UIMain);
                    imshow(imnoise(k,'poisson'),'Parent',app.UISmooth);
                    k_saltnpepper=imnoise(k,'poisson');
                    app.NoiseAdjust.Enable='off';
                    
            end
            
            
            app.Label1.Value=immse(k,getimage(app.UIMain));
            
            [~, second]=psnr(getimage(app.UIMain),k);
               app.SNR1.Value=second;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            page5_image=getimage(app.UISharpen);
            extra=app.ExtraButtonGroup.SelectedObject;
            extraText=extra.Text;
            
            switch extraText
                case 'Vintage'
                    
                    app.OtsuButton.Visible='off';
                    app.OtsuButton.Enable='off';
                    
                    app.Slider.Visible='off';
                    app.Slider.Enable='off';
                    
                    app.EditField.Visible='off';
                    app.EditField.Enable='off';
                    
                    vintage_b1=im2double(page5_image);
            
                    %Get input red, green and blue channels
                    iR_b1=vintage_b1(:,:,1);
                    iG_b1=vintage_b1(:,:,2);
                    iB_b1=vintage_b1(:,:,3);
                    
                    %Get output of R,G,B channels
                    oR_b1=(iR_b1*0.393) + (iG_b1*.769) + (iB_b1* .189)/(.393 + .769 + .189);
                    oG_b1=(iR_b1*0.349) + (iG_b1*.686) + (iB_b1* .168)/(.349 + .686 + .168);
                    oB_b1=(iR_b1*0.272) + (iG_b1*.534) + (iB_b1* .131)/(.272 + .534 + .131);
                    
                    %Concatenate the output channels to get 8-bit output image
                    vintage_b1_out=cat(3, oR_b1, oG_b1, oB_b1);
                    vintage_b1_out=uint8(255*vintage_b1_out);
                    imshow(vintage_b1_out,'Parent',app.UIExtra);
                    
                    app.SkipButton_Extra.Enable='on';
                    app.ContinueButton.Enable='on';
                    
               
                case 'Sobel'
                    app.OtsuButton.Visible='off';
                    app.OtsuButton.Enable='off';
                    
                    app.Slider.Visible='off';
                    app.Slider.Enable='off';
                    
                    app.EditField.Visible='off';
                    app.EditField.Enable='off';
                    
                    
                    imshow(edge(rgb2gray(page5_image),'Sobel'),'Parent',app.UIExtra);
                    app.SkipButton_Extra.Enable='off';
                    app.ContinueButton.Enable='off';
                    
                
                case 'Prewit'
                    
                    app.OtsuButton.Visible='off';
                    app.OtsuButton.Enable='off';
                    
                    app.Slider.Visible='off';
                    app.Slider.Enable='off';
                    
                    app.EditField.Visible='off';
                    app.EditField.Enable='off';
                    
                    imshow(edge(rgb2gray(page5_image),'Prewitt'),'Parent',app.UIExtra);
                    app.SkipButton_Extra.Enable='off';
                    app.ContinueButton.Enable='off';
                
                case 'Canny'
                    
                    app.OtsuButton.Visible='off';
                    app.OtsuButton.Enable='off';
                    
                    app.Slider.Visible='off';
                    app.Slider.Enable='off';
                    
                    app.EditField.Visible='off';
                    app.EditField.Enable='off';
                    
                    imshow(edge(rgb2gray(page5_image),'Canny'),'Parent',app.UIExtra);
                    app.SkipButton_Extra.Enable='off';
                    app.ContinueButton.Enable='off';
                    
                 case 'Roberts'
                     
                     app.OtsuButton.Visible='off';
                    app.OtsuButton.Enable='off';
                    
                    app.Slider.Visible='off';
                    app.Slider.Enable='off';
                    
                    app.EditField.Visible='off';
                    app.EditField.Enable='off';
                    
                    imshow(edge(rgb2gray(page5_image),'Roberts'),'Parent',app.UIExtra);
                    app.SkipButton_Extra.Enable='off';
                    app.ContinueButton.Enable='off';
                    
                 case 'Canny_BW'
                     
                     app.OtsuButton.Visible='off';
                    app.OtsuButton.Enable='off';
                    
                    app.Slider.Visible='off';
                    app.Slider.Enable='off';
                    
                    app.EditField.Visible='off';
                    app.EditField.Enable='off';
                    
                    imshow(edge((page5_image),'Canny'),'Parent',app.UIExtra);
                    app.SkipButton_Extra.Enable='off';
                    app.ContinueButton.Enable='off';
                    
                    case 'Prewit_BW'
                        
                        app.OtsuButton.Visible='off';
                    app.OtsuButton.Enable='off';
                    
                    app.Slider.Visible='off';
                    app.Slider.Enable='off';
                    
                    app.EditField.Visible='off';
                    app.EditField.Enable='off';
                    
                    imshow(edge((page5_image),'Prewitt'),'Parent',app.UIExtra);
                    app.SkipButton_Extra.Enable='off';
                    app.ContinueButton.Enable='off';
                    
                    case 'Sobel_BW'
                        
                        app.OtsuButton.Visible='off';
                    app.OtsuButton.Enable='off';
                    
                    app.Slider.Visible='off';
                    app.Slider.Enable='off';
                    
                    app.EditField.Visible='off';
                    app.EditField.Enable='off';
                    
                    
                    imshow(edge((page5_image),'Sobel'),'Parent',app.UIExtra);
                    app.SkipButton_Extra.Enable='off';
                    app.ContinueButton.Enable='off';
                    
                case 'Roberts_BW'
                    app.OtsuButton.Visible='off';
                    app.OtsuButton.Enable='off';
                    
                    app.Slider.Visible='off';
                    app.Slider.Enable='off';
                    
                    app.EditField.Visible='off';
                    app.EditField.Enable='off';
                    
                    imshow(edge((page5_image),'Roberts'),'Parent',app.UIExtra);
                    app.SkipButton_Extra.Enable='off';
                    app.ContinueButton.Enable='off';
                    
                case 'Binary'
                    
            
                    app.OtsuButton.Visible='on';
                    app.OtsuButton.Enable='on';
                    
                    app.Slider.Visible='on';
                    app.Slider.Enable='on';
                    
                    app.EditField.Visible='on';
                    app.EditField.Enable='on';
                    
            value=app.Slider.Value;
            app.EditField.Value=value;
            
                    otsu=app.Slider.Value;
                    double_g1=im2double(page5_image);
                    bin_g1=imbinarize(double_g1,otsu);
                    imshow(bin_g1,'Parent',app.UIExtra);
                    
                    app.SkipButton_Extra.Enable='off';
                    app.ContinueButton.Enable='off';
            end


        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            app.HistogramEquivalenceButton.Enable='on';
            
            
            fo=app.SmootheningFilterButtonGroup.SelectedObject;
            fotext=fo.Text;
            
             m=str2num(app.Knob.Value);
            
            page2_image=getimage(app.UIMain);
            h_avg=fspecial('average',m);
            h_gaus=fspecial('gaussian',m,app.Sigma.Value);
            
           
        switch fotext
            case 'Average'
                app.Knob.Enable='on';
                app.Sigma.Enable='off';
                imshow(imfilter(page2_image,h_avg),'Parent',app.UISmooth);
                
                app.CannyButton.Enable='on';
               
                app.SobelButton.Enable='on';
                app.VintageButton.Enable='on';
                app.RobertsButton.Enable='on';
                
                app.CannyButton.Visible='on';
               
                app.SobelButton.Visible='on';
                app.RobertsButton.Visible='on';
                
                app.VintageButton.Visible='on';
                
                
                app.CannyButtonBW.Enable='off';
                app.PrewittButtonBW.Enable='off';
                app.SobelButtonBW.Enable='off';
                app.RobertsButtonBW.Visible='off';
                
                app.CannyButtonBW.Visible='off';
                app.PrewittButtonBW.Visible='off';
                app.SobelButtonBW.Visible='off';
                app.RobertsButtonBW.Visible='off';
               
                app.BWButton.Enable='off';
                app.RGBButton.Enable='on';
                global k;  
               app.Label2.Value=immse(k,getimage(app.UISmooth));
                [~, second]=psnr(getimage(app.UISmooth),k);
               app.SNR2.Value=second;
                
                
            case 'Gaussian'
                app.Knob.Enable='on';
                app.Sigma.Enable='on';
                imshow(imfilter(page2_image,h_gaus),'Parent',app.UISmooth);
                
                  app.CannyButton.Enable='on';
               
                app.SobelButton.Enable='on';
                app.VintageButton.Enable='on';
                app.RobertsButton.Enable='on';
                
                app.CannyButton.Visible='on';
                
                app.SobelButton.Visible='on';
                app.RobertsButton.Enable='on';
               
                
                app.VintageButton.Visible='on';
                
                
                app.CannyButtonBW.Enable='off';
                app.PrewittButtonBW.Enable='off';
                app.SobelButtonBW.Enable='off';
                app.RobertsButtonBW.Enable='off';
              
                
                app.CannyButtonBW.Visible='off';
                app.PrewittButtonBW.Visible='off';
                app.SobelButtonBW.Visible='off';
                app.RobertsButtonBW.Visible='off';
           global k;  
               app.Label2.Value=immse(k,getimage(app.UISmooth));
               
               [~, second]=psnr(getimage(app.UISmooth),k);
               app.SNR2.Value=second;
               
               app.BWButton.Enable='off';
                app.RGBButton.Enable='on';
            
            case 'Median'
                app.Knob.Enable='on';
                app.Sigma.Enable='off';
                imshow(medfilt2(rgb2gray(page2_image), [m m]),'Parent',app.UISmooth);
                app.CannyButton.Enable='off';
                
                app.SobelButton.Enable='off';
                app.VintageButton.Enable='off';
                app.RobertsButton.Enable='off';
                
                app.CannyButton.Visible='off';
                
                app.SobelButton.Visible='off';
                app.RobertsButton.Visible='off';
               
                
                app.VintageButton.Visible='off';
                
                
                app.CannyButtonBW.Enable='on';
                app.PrewittButtonBW.Enable='on';
                app.SobelButtonBW.Enable='on';
                app.RobertsButtonBW.Enable='on';
              
                
                app.CannyButtonBW.Visible='on';
                app.PrewittButtonBW.Visible='on';
                app.SobelButtonBW.Visible='on';
                app.RobertsButtonBW.Visible='on';
                
                app.BWButton.Enable='on';
                app.RGBButton.Enable='off';
                
                global k;  
               app.Label2.Value=immse(rgb2gray(k),getimage(app.UISmooth));
                
                [~, second]=psnr(getimage(app.UISmooth),rgb2gray(k));
               app.SNR2.Value=second;
                
            case 'Edge Preserving'
                app.Knob.Enable='off';
                app.Sigma.Enable='off';
                imshow(imguidedfilter(rgb2gray(page2_image),rgb2gray(getimage(app.UIMain))),'Parent',app.UISmooth);
                
                app.CannyButton.Enable='off';
                
                app.SobelButton.Enable='off';
                app.VintageButton.Enable='off';
                app.RobertsButtonBW.Enable='off';
                
                app.CannyButton.Visible='off';
                
                app.SobelButton.Visible='off';
                app.VintageButton.Visible='off';
                app.RobertsButton.Visible='off';
                
                
                app.CannyButtonBW.Enable='on';
                app.PrewittButtonBW.Enable='on';
                app.SobelButtonBW.Enable='on';
                app.RobertsButtonBW.Enable='on';
              
                
                app.CannyButtonBW.Visible='on';
                app.PrewittButtonBW.Visible='on';
                app.SobelButtonBW.Visible='on';
                app.RobertsButtonBW.Visible='on';
                app.BWButton.Enable='on';
                app.RGBButton.Enable='off';
                global k;  
               app.Label2.Value=immse(rgb2gray(k),getimage(app.UISmooth));
                [~, second]=psnr(getimage(app.UISmooth),rgb2gray(k));
               app.SNR2.Value=second;
        end


        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.MainTab;
            app.Sigma.Enable='off';
        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
            app.TabGroup.SelectedTab=app.SmoothTab;
            app.RGBButton.Enable='on';
            app.BWButton.Enable='on';
            app.Label3.Value=0;
            app.SNR3.Value=0;
            app.error.Visible='off';
        end

        % Button pushed function: BackButton_3
        function BackButton_3Pushed(app, event)
             app.Label4.Enable='off';
            app.Label4.Enable='off';
            app.Sharpen4.Enable='off';
            app.Sharpen5.Enable='off';
            
            
            app.Label4.Visible='off';
            app.Label4.Visible='off';
            app.Sharpen4.Visible='off';
            app.Sharpen5.Visible='off';
            app.TabGroup.SelectedTab=app.HistogramTab;
        end

        % Button pushed function: BackButton_4
        function BackButton_4Pushed(app, event)
            app.TabGroup.SelectedTab=app.SharpenTab;
            
            app.OtsuButton.Visible='off';
                    app.OtsuButton.Enable='off';
                    
                    app.Slider.Visible='off';
                    app.Slider.Enable='off';
                    
                    app.EditField.Visible='off';
                    app.EditField.Enable='off';
                    
                    app.SkipButton_Extra.Enable='on';
                    app.ContinueButton.Enable='on';
                    
        end

        % Button pushed function: BackButton_5
        function BackButton_5Pushed(app, event)
            app.TabGroup.SelectedTab=app.ExtraTab;
            app.ContinueButton.Enable='off';
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            
            global k;
            global k_gaussian;
            global k_speckle;
            global k_saltnpepper;
            app.SkipMain.Visible='on';
            
            app.message.Visible='off';
            
            [filename , pathname]=uigetfile({'*jpg'},'File Selector');
            fullpathname = strcat (pathname, filename);
            k =imread(fullpathname);
            imshow(k,'Parent',app.UIMain);
            imshow(k,'Parent',app.UIFaces);
        end

        % Callback function
        function ChoiseSwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch value
                case 'on'
                    app.Message.Text='Yes, I am ready';
                    app.HitMeButton.Enable='on';
                case 'off'
                    app.Message.Text='No, I am not ready';
                    app.HitMeButton.Enable='off';
            end
            
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.FacesTab;
            imshow(getimage(app.UIExtra),'Parent',app.UIFaces);
            app.message.Visible='off';
        end

        % Button pushed function: DecreaseContrastButton
        function DecreaseContrastButtonPushed(app, event)
            global page3_image;
            imshow(page3_image,'Parent',app.UIHistogram);
            app.Label3.Value=app.Label2.Value;
            
            
            app.SNR3.Value=app.SNR2.Value;
            app.error.Visible='off';
            
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            image_req=getimage(app.UIFaces);
            
            faceDetector = vision.CascadeObjectDetector();
            
           
            bbox = step(faceDetector, image_req);
            
            % Draw the returned bounding box around the detected face
            B = insertObjectAnnotation(image_req, 'rectangle', bbox, 'Face');
            imshow(B,'Parent',app.UIFaces);
            
            % Print number of detected faces
            
            str = num2str(size(bbox, 1));
            str = strcat('Number of detected faces = ', str);
            disp(str);
            
            nof=size(bbox, 1);
            
            app.NumberofFacesEditField.Value=nof;
            
            if nof>3
                app.Proceed.Enable='On';
            else
                app.message.Visible='on';
            end


        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
         ;
            
        end

        % Button pushed function: ExtraButton
        function ExtraButtonPushed(app, event)
            global page5_image;
            page5_image=getimage(app.UISharpen);
            imshow(page5_image, 'Parent',app.UIExtra);
            app.TabGroup.SelectedTab=app.ExtraTab;
        end

        % Button pushed function: HistogramEquivalenceButton
        function HistogramEquivalenceButtonPushed(app, event)
            global page3_image; 
            page3_image=getimage(app.UISmooth);
            imshow(page3_image,'Parent',app.UIHistogram);
            app.TabGroup.SelectedTab=app.HistogramTab;
            
        end

        % Button pushed function: HitMeButton
        function HitMeButtonPushed(app, event)
            macopen('setup.exe');
        end

        % Button pushed function: IncreaseContrastButton
        function IncreaseContrastButtonPushed(app, event)
            app.DecreaseContrastButton.Enable='on';
            rgbvalue=app.ButtonGroup.SelectedObject;
            page3_image=getimage(app.UIHistogram);
            rgbvalue_new=rgbvalue.Text;
            
            switch rgbvalue_new
                case 'B/W'
                    app.VintageButton.Enable='off';
                    app.SobelButton.Enable='on';
                    app.CannyButton.Enable='on';
                   
                    imshow(histeq(page3_image),'Parent',app.UIHistogram);
                    
              app.CannyButton.Enable='off';
               
                app.SobelButton.Enable='off';
                app.VintageButton.Enable='off';
                app.RobertsButton.Enable='off';
                
                app.CannyButton.Visible='off';
                
                app.SobelButton.Visible='off';
                app.RobertsButton.Visible='off';
               
                
                app.VintageButton.Visible='off';
                
                
                app.CannyButtonBW.Enable='on';
                app.PrewittButtonBW.Enable='on';
                app.SobelButtonBW.Enable='on';
                app.RobertsButtonBW.Enable='on';
              
                
                app.CannyButtonBW.Visible='on';
                app.PrewittButtonBW.Visible='on';
                app.SobelButtonBW.Visible='on';
                app.RobertsButtonBW.Visible='on';
                
                app.RGBButton.Enable='off';
                global k;
                app.Label3.Visible='on';
                app.error.Visible='off';
                app.Label3.Value=immse(getimage(app.UIHistogram),rgb2gray(k));
                app.SNR3.Value=snr(getimage(app.UIHistogram),rgb2gray(k));
                
                
                 app.Label5.Enable='on';
            app.Label4.Enable='off';
            app.Sharpen4.Enable='off';
            app.Sharpen5.Enable='on';
            
            
            app.Label4.Visible='off';
            app.Label5.Visible='on';
            app.Sharpen4.Visible='off';
            app.Sharpen5.Visible='on';
                case 'RGB'
                    app.VintageButton.Enable='on';
                    hsv_image=rgb2hsv(page3_image);
                    hsv_v=hsv_image(:,:,3);
                    eq=histeq(hsv_v);
                    hsv_image(:,:,3)=eq;
                    image_final=hsv2rgb(hsv_image);
                    imshow(image_final,'Parent',app.UIHistogram);
                   
                
                  app.CannyButton.Enable='on';
               
                app.SobelButton.Enable='on';
                app.VintageButton.Enable='on';
                app.RobertsButton.Enable='on';
                
                app.CannyButton.Visible='on';
                
                app.SobelButton.Visible='on';
                app.RobertsButton.Visible='on';
                
                app.VintageButton.Visible='on';
                
                
                app.CannyButtonBW.Enable='off';
                app.PrewittButtonBW.Enable='off';
                app.SobelButtonBW.Enable='off';
                app.RobertsButtonBW.Visible='off';
                
                app.CannyButtonBW.Visible='off';
                app.PrewittButtonBW.Visible='off';
                app.SobelButtonBW.Visible='off';
                app.RobertsButtonBW.Visible='off';
                
                app.BWButton.Enable='off';
                global k;
                app.error.Visible='on';
                app.Label3.Visible='on';
                app.SNR3.Value=snr(getimage(app.UIHistogram),rgb2hsv(k));
                
                 app.Label5.Enable='off';
            app.Label4.Enable='on';
            app.Sharpen4.Enable='on';
            app.Sharpen5.Enable='off';
            
            
            app.Label4.Visible='on';
            app.Label5.Visible='off';
            app.Sharpen4.Visible='on';
            app.Sharpen5.Visible='off';
                
            end


        end

        % Button pushed function: JUMPButton
        function JUMPButtonPushed(app, event)
            global image1;
            image1=getimage(app.UIMain);
            imshow(image1,'Parent',app.UIFaces);
            app.TabGroup.SelectedTab=app.FacesTab;
            
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
          app.HistogramEquivalenceButton.Enable='on';
            
            
            fo=app.SmootheningFilterButtonGroup.SelectedObject;
            fotext=fo.Text;
            
             m=str2num(app.Knob.Value);
            
            page2_image=getimage(app.UIMain);
            h_avg=fspecial('average',m);
            h_gaus=fspecial('gaussian',m,app.Sigma.Value);
            
           
        switch fotext
            case 'Average'
                app.Sigma.Enable='off';
                imshow(imfilter(page2_image,h_avg),'Parent',app.UISmooth);
                
                app.CannyButton.Enable='on';
               
                app.SobelButton.Enable='on';
                app.VintageButton.Enable='on';
                app.RobertsButton.Enable='on';
                
                app.CannyButton.Visible='on';
               
                app.SobelButton.Visible='on';
                app.RobertsButton.Visible='on';
                
                app.VintageButton.Visible='on';
                
                
                app.CannyButtonBW.Enable='off';
                app.PrewittButtonBW.Enable='off';
                app.SobelButtonBW.Enable='off';
                app.RobertsButtonBW.Visible='off';
                
                app.CannyButtonBW.Visible='off';
                app.PrewittButtonBW.Visible='off';
                app.SobelButtonBW.Visible='off';
                app.RobertsButtonBW.Visible='off';
                
                global k;  
               app.Label2.Value=immse(k,getimage(app.UISmooth));
               
               [~, second]=psnr(getimage(app.UISmooth),(k));
               app.SNR2.Value=second;
                
                
            case 'Gaussian'
                app.Sigma.Enable='on';
                imshow(imfilter(page2_image,h_gaus),'Parent',app.UISmooth);
                
                  app.CannyButton.Enable='on';
               
                app.SobelButton.Enable='on';
                app.VintageButton.Enable='on';
                app.RobertsButton.Enable='on';
                
                app.CannyButton.Visible='on';
                
                app.SobelButton.Visible='on';
                app.RobertsButton.Enable='on';
               
                
                app.VintageButton.Visible='on';
                
                
                app.CannyButtonBW.Enable='off';
                app.PrewittButtonBW.Enable='off';
                app.SobelButtonBW.Enable='off';
                app.RobertsButtonBW.Enable='off';
              
                
                app.CannyButtonBW.Visible='off';
                app.PrewittButtonBW.Visible='off';
                app.SobelButtonBW.Visible='off';
                app.RobertsButtonBW.Visible='off';
           
                global k;  
               app.Label2.Value=immse(k,getimage(app.UISmooth));
            [~, second]=psnr(getimage(app.UISmooth),(k));
               app.SNR2.Value=second;
            case 'Median'
                app.Sigma.Enable='off';
                imshow(medfilt2(rgb2gray(page2_image), [m m]),'Parent',app.UISmooth);
                app.CannyButton.Enable='off';
                
                app.SobelButton.Enable='off';
                app.VintageButton.Enable='off';
                app.RobertsButton.Enable='off';
                
                app.CannyButton.Visible='off';
                
                app.SobelButton.Visible='off';
                app.RobertsButton.Visible='off';
               
                
                app.VintageButton.Visible='off';
                
                
                app.CannyButtonBW.Enable='on';
                app.PrewittButtonBW.Enable='on';
                app.SobelButtonBW.Enable='on';
                app.RobertsButtonBW.Enable='on';
              
                
                app.CannyButtonBW.Visible='on';
                app.PrewittButtonBW.Visible='on';
                app.SobelButtonBW.Visible='on';
                app.RobertsButtonBW.Visible='on';
                
                app.RGBButton.Enable='off';
                
                
                global k;  
               app.Label2.Value=immse(rgb2gray(k),getimage(app.UISmooth));
                [~, second]=psnr(getimage(app.UISmooth),rgb2gray(k));
               app.SNR2.Value=second;
               
            case 'Edge Preserving'
                app.Sigma.Enable='off';
                imshow(imguidedfilter(rgb2gray(page2_image),rgb2gray(getimage(app.UIMain))),'Parent',app.UISmooth);
                
                app.CannyButton.Enable='off';
                
                app.SobelButton.Enable='off';
                app.VintageButton.Enable='off';
                app.RobertsButtonBW.Enable='off';
                
                app.CannyButton.Visible='off';
                
                app.SobelButton.Visible='off';
                app.VintageButton.Visible='off';
                app.RobertsButton.Visible='off';
                
                
                app.CannyButtonBW.Enable='on';
                app.PrewittButtonBW.Enable='on';
                app.SobelButtonBW.Enable='on';
                app.RobertsButtonBW.Enable='on';
              
                
                app.CannyButtonBW.Visible='on';
                app.PrewittButtonBW.Visible='on';
                app.SobelButtonBW.Visible='on';
                app.RobertsButtonBW.Visible='on';
                app.RGBButton.Enable='off';
                
               global k;  
               app.Label2.Value=immse(rgb2gray(k),getimage(app.UISmooth));
                [~, second]=psnr(getimage(app.UISmooth),rgb2gray(k));
               app.SNR2.Value=second;
        end


        end

        % Value changed function: NoiseAdjust
        function NoiseAdjustValueChanged(app, event)
            app.GaugeText.Value = app.NoiseAdjust.Value;
          
            app.RemoveNoiseButton.Enable='on';
            GaugeTextValue=app.GaugeText.Value;
            global k;
            global k_gaussian;
            global k_speckle;
            global k_saltnpepper;
            
            option=app.NoiseButtonGroup.SelectedObject;
            value=option.Text;
            
            app.OriginalButton.Visible='on';
            switch value
                case 'Gaussian'
                    app.GaugeText.Enable='on';
                    app.NoiseAdjust.Enable='on';
                    imshow(imnoise(k,'gaussian',GaugeTextValue),'Parent',app.UIMain);
                    imshow(imnoise(k,'gaussian',GaugeTextValue),'Parent',app.UISmooth);
                    k_gaussian=imnoise(k,'gaussian');


                case 'Speckle'
                    app.GaugeText.Enable='on';
                    app.NoiseAdjust.Enable='on';
                    imshow(imnoise(k,'speckle',GaugeTextValue),'Parent',app.UIMain);
                    imshow(imnoise(k,'speckle',GaugeTextValue),'Parent',app.UISmooth);
                   
                   
                    k_speckle=imnoise(k,'speckle');
                    
                    
                case 'Salt-Pepper'
                    
                    app.GaugeText.Enable='on';
                    app.NoiseAdjust.Enable='on';
                    imshow(imnoise(k,'salt & pepper',GaugeTextValue),'Parent',app.UIMain);
                    imshow(imnoise(k,'salt & pepper',GaugeTextValue),'Parent',app.UISmooth);
                   
                    
                    k_saltnpepper=imnoise(k,'salt & pepper');
                    
                case 'Poisson'
                    
                    imshow(imnoise(k,'poisson'),'Parent',app.UIMain);
                    imshow(imnoise(k,'poisson'),'Parent',app.UISmooth);
                    k_saltnpepper=imnoise(k,'poisson');
                    
                    app.GaugeText.Enable='off';
                    
                    
            end
            global k;
            app.Label1.Value=immse(k,getimage(app.UIMain));
             [~, second]=psnr(getimage(app.UIMain),k);
               app.SNR1.Value=second;
        end

        % Value changed function: NumberofFacesEditField
        function NumberofFacesEditFieldValueChanged(app, event)
            value = app.NumberofFacesEditField.Value;
            
        end

        % Button pushed function: OriginalButton
        function OriginalButtonPushed(app, event)
            global k;
            imshow(k,'Parent',app.UIMain);
        end

        % Button pushed function: OtsuButton
        function OtsuButtonPushed(app, event)
            page4_image=getimage(app.UISharpen);        
            otsu=graythresh(page4_image);
                    
                    app.Slider.Value=otsu;
                    app.EditField.Value=otsu;
                    
        end

        % Button pushed function: Proceed
        function ProceedButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.WinTab;
        end

        % Button pushed function: RemoveNoiseButton
        function RemoveNoiseButtonPushed(app, event)
            image1=getimage(app.UIMain);
            app.TabGroup.SelectedTab=app.SmoothTab;
            imshow(image1,'Parent',app.UISmooth);
           
        end

        % Button pushed function: Sharpen4
        function Sharpen4Pushed(app, event)
            global page4_image;
            imshow(imsharpen(page4_image),'Parent',app.UISharpen);
            global k;
            app.Label4.Value=snr(getimage(app.UISharpen),rgb2hsv(k));
           
            
        end

        % Button pushed function: Sharpen5
        function Sharpen5ButtonPushed(app, event)
            global page4_image;
            imshow(imsharpen(page4_image),'Parent',app.UISharpen);           
            global k;
            app.Label5.Value=snr(getimage(app.UISharpen),rgb2gray(k));
        end

        % Button pushed function: SharpenButton
        function SharpenButtonPushed(app, event)
            global page4_image;
            page4_image=getimage(app.UIHistogram);
            app.TabGroup.SelectedTab=app.SharpenTab;
            imshow(page4_image,'Parent',app.UISharpen);
        end

        % Value changed function: Sigma
        function SigmaValueChanged(app, event)
          app.HistogramEquivalenceButton.Enable='on';
            global k;
            
            fo=app.SmootheningFilterButtonGroup.SelectedObject;
            fotext=fo.Text;
            
             m=str2num(app.Knob.Value);
            
            page2_image=getimage(app.UIMain);
            h_avg=fspecial('average',m);
            h_gaus=fspecial('gaussian',m,app.Sigma.Value);
            
           
        switch fotext
            case 'Average'
                app.Sigma.Enable='off';
                imshow(imfilter(page2_image,h_avg),'Parent',app.UISmooth);
                
                app.CannyButton.Enable='on';
               
                app.SobelButton.Enable='on';
                app.VintageButton.Enable='on';
                app.RobertsButton.Enable='on';
                
                app.CannyButton.Visible='on';
               
                app.SobelButton.Visible='on';
                app.RobertsButton.Visible='on';
                
                app.VintageButton.Visible='on';
                
                
                app.CannyButtonBW.Enable='off';
                app.PrewittButtonBW.Enable='off';
                app.SobelButtonBW.Enable='off';
                app.RobertsButtonBW.Visible='off';
                
                app.CannyButtonBW.Visible='off';
                app.PrewittButtonBW.Visible='off';
                app.SobelButtonBW.Visible='off';
                app.RobertsButtonBW.Visible='off';
                global k;
                [~, second]=psnr(getimage(app.UISmooth),(k));
               app.SNR2.Value=second;
                
            case 'Gaussian'
                app.Sigma.Enable='on';
                imshow(imfilter(page2_image,h_gaus),'Parent',app.UISmooth);
                
                  app.CannyButton.Enable='on';
               
                app.SobelButton.Enable='on';
                app.VintageButton.Enable='on';
                app.RobertsButton.Enable='on';
                
                app.CannyButton.Visible='on';
                
                app.SobelButton.Visible='on';
                app.RobertsButton.Enable='on';
               
                
                app.VintageButton.Visible='on';
                
                
                app.CannyButtonBW.Enable='off';
                app.PrewittButtonBW.Enable='off';
                app.SobelButtonBW.Enable='off';
                app.RobertsButtonBW.Enable='off';
              
                
                app.CannyButtonBW.Visible='off';
                app.PrewittButtonBW.Visible='off';
                app.SobelButtonBW.Visible='off';
                app.RobertsButtonBW.Visible='off';
           global k;
           app.Label2.Value=immse(getimage(app.UISmooth),k);
                [~, second]=psnr(getimage(app.UISmooth),(k));
               app.SNR2.Value=second;
               
            case 'Median'
                app.Sigma.Enable='off';
                imshow(medfilt2(rgb2gray(page2_image), [m m]),'Parent',app.UISmooth);
                app.CannyButton.Enable='off';
                
                app.SobelButton.Enable='off';
                app.VintageButton.Enable='off';
                app.RobertsButton.Enable='off';
                
                app.CannyButton.Visible='off';
                
                app.SobelButton.Visible='off';
                app.RobertsButton.Visible='off';
               
                
                app.VintageButton.Visible='off';
                
                
                app.CannyButtonBW.Enable='on';
                app.PrewittButtonBW.Enable='on';
                app.SobelButtonBW.Enable='on';
                app.RobertsButtonBW.Enable='on';
              
                
                app.CannyButtonBW.Visible='on';
                app.PrewittButtonBW.Visible='on';
                app.SobelButtonBW.Visible='on';
                app.RobertsButtonBW.Visible='on';
                
                app.RGBButton.Enable='off';
                global k;
                app.Label2.Value=immse(k,getimage(app.UISmooth));
                [~, second]=psnr(getimage(app.UISmooth),rgb2gray(k));
               app.SNR2.Value=second;
                
                
            case 'Edge Preserving'
                app.Sigma.Enable='off';
                imshow(imguidedfilter(rgb2gray(page2_image),rgb2gray(getimage(app.UIMain))),'Parent',app.UISmooth);
                
                app.CannyButton.Enable='off';
                
                app.SobelButton.Enable='off';
                app.VintageButton.Enable='off';
                app.RobertsButtonBW.Enable='off';
                
                app.CannyButton.Visible='off';
                
                app.SobelButton.Visible='off';
                app.VintageButton.Visible='off';
                app.RobertsButton.Visible='off';
                
                
                app.CannyButtonBW.Enable='on';
                app.PrewittButtonBW.Enable='on';
                app.SobelButtonBW.Enable='on';
                app.RobertsButtonBW.Enable='on';
              
                
                app.CannyButtonBW.Visible='on';
                app.PrewittButtonBW.Visible='on';
                app.SobelButtonBW.Visible='on';
                app.RobertsButtonBW.Visible='on';
                app.RGBButton.Enable='off';
                
                global k;  
               app.Label2.Value=immse(k,getimage(app.UISmooth));
               [~, second]=psnr(getimage(app.UISmooth),rgb2gray(k));
               app.SNR2.Value=second;
        end
             
        end

        % Callback function
        function SkipButtonPushed(app, event)
           global k
            app.TabGroup.SelectedTab=app.SmoothTab;
            imshow(k,'Parent',app.UISmooth);
            
        end

        % Button pushed function: SkipButton_Extra
        function SkipButton_ExtraPushed(app, event)
            app.TabGroup.SelectedTab=app.FacesTab;
            k2=getimage(app.UIExtra);
            imshow(k2,'Parent',app.UIFaces);
        end

        % Callback function
        function SkipHistogramButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.SharpenTab;
        end

        % Callback function
        function SkipMainButtonPushed(app, event)
           global k;
            app.TabGroup.SelectedTab=app.SmoothTab;
            imshow(k,'Parent',app.UISmooth);
        end

        % Button pushed function: SkipSmooth
        function SkipSmoothButtonPushed(app, event)
            k=getimage(app.UISmooth);
            app.TabGroup.SelectedTab=app.HistogramTab;
            imshow(k,'Parent',app.UIHistogram);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
           
         page5_image=getimage(app.UISharpen);
            extra=app.ExtraButtonGroup.SelectedObject;
            extraText=extra.Text;
            
            switch extraText
                case 'Vintage'
                    vintage_b1=im2double(page5_image);
            
                    %Get input red, green and blue channels
                    iR_b1=vintage_b1(:,:,1);
                    iG_b1=vintage_b1(:,:,2);
                    iB_b1=vintage_b1(:,:,3);
                    
                    %Get output of R,G,B channels
                    oR_b1=(iR_b1*0.393) + (iG_b1*.769) + (iB_b1* .189)/(.393 + .769 + .189);
                    oG_b1=(iR_b1*0.349) + (iG_b1*.686) + (iB_b1* .168)/(.349 + .686 + .168);
                    oB_b1=(iR_b1*0.272) + (iG_b1*.534) + (iB_b1* .131)/(.272 + .534 + .131);
                    
                    %Concatenate the output channels to get 8-bit output image
                    vintage_b1_out=cat(3, oR_b1, oG_b1, oB_b1);
                    vintage_b1_out=uint8(255*vintage_b1_out);
                    imshow(vintage_b1_out,'Parent',app.UIExtra);
                    
                    app.SkipButton_Extra.Enable='on';
                    app.ContinueButton.Enable='on';
                    
               
                case 'Sobel'
                    
                    imshow(edge(rgb2gray(page5_image),'Sobel'),'Parent',app.UIExtra);
                    app.SkipButton_Extra.Enable='off';
                    app.ContinueButton.Enable='off';
                    
                
                case 'Prewit'
                    imshow(edge(rgb2gray(page5_image),'Prewitt'),'Parent',app.UIExtra);
                    app.SkipButton_Extra.Enable='off';
                    app.ContinueButton.Enable='off';
                
                case 'Canny'
                    imshow(edge(rgb2gray(page5_image),'Canny'),'Parent',app.UIExtra);
                    app.SkipButton_Extra.Enable='off';
                    app.ContinueButton.Enable='off';
                    
                 case 'Roberts'
                    imshow(edge(rgb2gray(page5_image),'Roberts'),'Parent',app.UIExtra);
                    app.SkipButton_Extra.Enable='off';
                    app.ContinueButton.Enable='off';
                    
                 case 'Canny_BW'
                    imshow(edge((page5_image),'Canny'),'Parent',app.UIExtra);
                    app.SkipButton_Extra.Enable='off';
                    app.ContinueButton.Enable='off';
                    
                    case 'Prewit_BW'
                    imshow(edge((page5_image),'Prewitt'),'Parent',app.UIExtra);
                    app.SkipButton_Extra.Enable='off';
                    app.ContinueButton.Enable='off';
                    
                    case 'Sobel_BW'
                    
                    imshow(edge((page5_image),'Sobel'),'Parent',app.UIExtra);
                    app.SkipButton_Extra.Enable='off';
                    app.ContinueButton.Enable='off';
                    
                case 'Roberts_BW'
                    imshow(edge((page5_image),'Roberts'),'Parent',app.UIExtra);
                    app.SkipButton_Extra.Enable='off';
                    app.ContinueButton.Enable='off';
                    
                case 'Binary'
                    
            
                    app.OtsuButton.Visible='on';
                    app.OtsuButton.Enable='on';
                    
                    app.Slider.Visible='on';
                    app.Slider.Enable='on';
                    
                    app.EditField.Visible='on';
                    app.EditField.Enable='on';
                    
            value=app.Slider.Value;
            app.EditField.Value=value;
            
                    otsu=app.Slider.Value;
                    double_g1=im2double(page5_image);
                    bin_g1=imbinarize(double_g1,otsu);
                    imshow(bin_g1,'Parent',app.UIExtra);
                    
                    app.SkipButton_Extra.Enable='off';
                    app.ContinueButton.Enable='off';
            end
            
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch value
                case 'Yes'
                    app.HitMeButton.Enable='on';
                case 'No'
                    app.HitMeButton.Enable='off';
            end
            
                    
        end

        % Value changed function: message
        function messageValueChanged(app, event)
            value = app.message.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.BrowseButton = uibutton(app.MainTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.NoiseButtonGroup = uibuttongroup(app.MainTab);
            app.NoiseButtonGroup.Title = 'Noise';
            app.GaussianButton = uiradiobutton(app.NoiseButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.GaussianButton.Value = true;
            app.SpeckleButton = uiradiobutton(app.NoiseButtonGroup);
            app.SpeckleButton.Text = 'Speckle';
            app.SaltPepperButton = uiradiobutton(app.NoiseButtonGroup);
            app.SaltPepperButton.Text = 'Salt-Pepper';
            app.PoissonButton = uiradiobutton(app.NoiseButtonGroup);
            app.PoissonButton.Text = 'Poisson';
            app.RemoveNoiseButton = uibutton(app.MainTab, 'push');
            app.RemoveNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveNoiseButtonPushed, true);
            app.RemoveNoiseButton.Enable = 'off';
            app.RemoveNoiseButton.Text = 'Remove Noise';
            app.AddNoiseButton = uibutton(app.MainTab, 'push');
            app.AddNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddNoiseButtonPushed, true);
            app.AddNoiseButton.Text = 'Add Noise';
            app.OriginalButton = uibutton(app.MainTab, 'push');
            app.OriginalButton.ButtonPushedFcn = createCallbackFcn(app, @OriginalButtonPushed, true);
            app.OriginalButton.Visible = 'off';
            app.OriginalButton.Text = 'Original';
            app.UIMain = uiaxes(app.MainTab);
            app.JUMPButton = uibutton(app.MainTab, 'push');
            app.JUMPButton.ButtonPushedFcn = createCallbackFcn(app, @JUMPButtonPushed, true);
            app.JUMPButton.Text = 'JUMP';
            app.NoiseAdjust = uislider(app.MainTab);
            app.NoiseAdjust.Limits = [0 1];
            app.NoiseAdjust.MajorTicks = [];
            app.NoiseAdjust.MajorTickLabels = {};
            app.NoiseAdjust.ValueChangedFcn = createCallbackFcn(app, @NoiseAdjustValueChanged, true);
            app.NoiseAdjust.MinorTicks = [];
            app.NoiseAdjust.Enable = 'off';
            app.NoiseAdjust.Value = 0.05;
            app.GaugeText = uieditfield(app.MainTab, 'numeric');
            app.GaugeText.Limits = [0 1];
            app.GaugeText.ValueDisplayFormat = '%.2f';
            app.GaugeText.Enable = 'off';
            app.GaugeText.Value = 0.05;
            app.Label1 = uieditfield(app.MainTab, 'numeric');
            app.Label1.Limits = [0 Inf];
            app.Label1.ValueDisplayFormat = '%.2f';
            app.MSE = uilabel(app.MainTab);
            app.MSE.Text = 'MSE';
            app.SNRLabel_2 = uilabel(app.MainTab);
            app.SNRLabel_2.Text = 'SNR';
            app.SNR1 = uieditfield(app.MainTab, 'numeric');
            app.SNR1.ValueDisplayFormat = '%.2f';
            app.SmoothTab = uitab(app.TabGroup);
            app.SmoothTab.Title = 'Smooth';
            app.BackButton = uibutton(app.SmoothTab, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.SmootheningFilterButtonGroup = uibuttongroup(app.SmoothTab);
            app.SmootheningFilterButtonGroup.Title = 'Smoothening Filter';
            app.GaussianButton_2 = uiradiobutton(app.SmootheningFilterButtonGroup);
            app.GaussianButton_2.Text = 'Gaussian';
            app.GaussianButton_2.Value = true;
            app.AverageButton_2 = uiradiobutton(app.SmootheningFilterButtonGroup);
            app.AverageButton_2.Text = 'Average';
            app.MedianButton_2 = uiradiobutton(app.SmootheningFilterButtonGroup);
            app.MedianButton_2.Text = 'Median';
            app.EdgePreservingButton_2 = uiradiobutton(app.SmootheningFilterButtonGroup);
            app.EdgePreservingButton_2.Text = 'Edge Preserving';
            app.ApplyFilterButton = uibutton(app.SmoothTab, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.HistogramEquivalenceButton = uibutton(app.SmoothTab, 'push');
            app.HistogramEquivalenceButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramEquivalenceButtonPushed, true);
            app.HistogramEquivalenceButton.Enable = 'off';
            app.HistogramEquivalenceButton.Text = {'Histogram '; 'Equivalence'};
            app.UISmooth = uiaxes(app.SmoothTab);
            app.SkipSmooth = uibutton(app.SmoothTab, 'push');
            app.SkipSmooth.ButtonPushedFcn = createCallbackFcn(app, @SkipSmoothButtonPushed, true);
            app.SkipSmooth.Text = 'Skip';
            app.Knob = uiknob(app.SmoothTab, 'discrete');
            app.Knob.Items = {'3', '5', '8', '15', '30', '50'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = '3';
            app.Sigma = uiknob(app.SmoothTab, 'continuous');
            app.Sigma.Limits = [1e-12 1];
            app.Sigma.MajorTicks = [1e-12 0.100000000001 0.200000000001 0.300000000001 0.400000000001 0.500000000001 0.600000000001 0.700000000001 0.800000000001 1];
            app.Sigma.MajorTickLabels = {'0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '1'};
            app.Sigma.ValueChangedFcn = createCallbackFcn(app, @SigmaValueChanged, true);
            app.Sigma.Enable = 'off';
            app.Sigma.Value = 0.5;
            app.MSELabel = uilabel(app.SmoothTab);
            app.MSELabel.Text = 'MSE';
            app.Label2 = uieditfield(app.SmoothTab, 'numeric');
            app.Label2.Limits = [0 Inf];
            app.Label2.ValueDisplayFormat = '%.2f';
            app.SNRLabel_3 = uilabel(app.SmoothTab);
            app.SNRLabel_3.Text = 'SNR';
            app.SNR2 = uieditfield(app.SmoothTab, 'numeric');
            app.SNR2.Limits = [0 Inf];
            app.SNR2.ValueDisplayFormat = '%.2f';
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.BackButton_2 = uibutton(app.HistogramTab, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = 'Back';
            app.SharpenButton = uibutton(app.HistogramTab, 'push');
            app.SharpenButton.ButtonPushedFcn = createCallbackFcn(app, @SharpenButtonPushed, true);
            app.SharpenButton.Text = 'Sharpen';
            app.IncreaseContrastButton = uibutton(app.HistogramTab, 'push');
            app.IncreaseContrastButton.ButtonPushedFcn = createCallbackFcn(app, @IncreaseContrastButtonPushed, true);
            app.IncreaseContrastButton.Text = 'Increase Contrast';
            app.ButtonGroup = uibuttongroup(app.HistogramTab);
            app.ButtonGroup.Title = 'Button Group';
            app.RGBButton = uiradiobutton(app.ButtonGroup);
            app.RGBButton.Text = 'RGB';
            app.RGBButton.Value = true;
            app.BWButton = uiradiobutton(app.ButtonGroup);
            app.BWButton.Text = 'B/W';
            app.UIHistogram = uiaxes(app.HistogramTab);
            app.DecreaseContrastButton = uibutton(app.HistogramTab, 'push');
            app.DecreaseContrastButton.ButtonPushedFcn = createCallbackFcn(app, @DecreaseContrastButtonPushed, true);
            app.DecreaseContrastButton.Enable = 'off';
            app.DecreaseContrastButton.Text = 'Decrease Contrast';
            app.MSELabel_2 = uilabel(app.HistogramTab);
            app.MSELabel_2.Text = 'MSE';
            app.Label3 = uieditfield(app.HistogramTab, 'numeric');
            app.Label3.Limits = [0 Inf];
            app.Label3.ValueDisplayFormat = '%.2f';
            app.error = uilabel(app.HistogramTab);
            app.error.Visible = 'off';
            app.error.Text = 'MSE Not Applicable';
            app.SNRLabel = uilabel(app.HistogramTab);
            app.SNRLabel.Text = 'SNR';
            app.SNR3 = uieditfield(app.HistogramTab, 'numeric');
            app.SNR3.ValueDisplayFormat = '%.2f';
            app.SharpenTab = uitab(app.TabGroup);
            app.SharpenTab.Title = 'Sharpen';
            app.BackButton_3 = uibutton(app.SharpenTab, 'push');
            app.BackButton_3.ButtonPushedFcn = createCallbackFcn(app, @BackButton_3Pushed, true);
            app.BackButton_3.Text = 'Back';
            app.Sharpen4 = uibutton(app.SharpenTab, 'push');
            app.Sharpen4.ButtonPushedFcn = createCallbackFcn(app, @Sharpen4Pushed, true);
            app.Sharpen4.Enable = 'off';
            app.Sharpen4.Visible = 'off';
            app.Sharpen4.Text = 'SharpenR';
            app.ExtraButton = uibutton(app.SharpenTab, 'push');
            app.ExtraButton.ButtonPushedFcn = createCallbackFcn(app, @ExtraButtonPushed, true);
            app.ExtraButton.Text = 'Extra';
            app.UISharpen = uiaxes(app.SharpenTab);
            app.SNR_rgb = uilabel(app.SharpenTab);
            app.SNR_rgb.Text = 'SNR';
            app.Label4 = uieditfield(app.SharpenTab, 'numeric');
            app.Label4.ValueDisplayFormat = '%.2f';
            app.Label4.Visible = 'off';
            app.Label5 = uieditfield(app.SharpenTab, 'numeric');
            app.Label5.ValueDisplayFormat = '%.2f';
            app.Label5.Visible = 'off';
            app.Sharpen5 = uibutton(app.SharpenTab, 'push');
            app.Sharpen5.ButtonPushedFcn = createCallbackFcn(app, @Sharpen5ButtonPushed, true);
            app.Sharpen5.Enable = 'off';
            app.Sharpen5.Visible = 'off';
            app.Sharpen5.Text = 'SharpenB';
            app.ExtraTab = uitab(app.TabGroup);
            app.ExtraTab.Title = 'Extra';
            app.ExtraButtonGroup = uibuttongroup(app.ExtraTab);
            app.ExtraButtonGroup.Title = 'Extra';
            app.VintageButton = uiradiobutton(app.ExtraButtonGroup);
            app.VintageButton.Text = 'Vintage';
            app.VintageButton.Value = true;
            app.BinaryButton = uiradiobutton(app.ExtraButtonGroup);
            app.BinaryButton.Text = 'Binary';
            app.SobelButton = uiradiobutton(app.ExtraButtonGroup);
            app.SobelButton.Text = 'Sobel';
            app.CannyButton = uiradiobutton(app.ExtraButtonGroup);
            app.CannyButton.Text = 'Canny';
            app.PrewittButtonBW = uiradiobutton(app.ExtraButtonGroup);
            app.PrewittButtonBW.Text = 'Prewit_BW';
            app.CannyButtonBW = uiradiobutton(app.ExtraButtonGroup);
            app.CannyButtonBW.Text = 'Canny_BW';
            app.SobelButtonBW = uiradiobutton(app.ExtraButtonGroup);
            app.SobelButtonBW.Text = 'Sobel_BW';
            app.RobertsButtonBW = uiradiobutton(app.ExtraButtonGroup);
            app.RobertsButtonBW.Text = 'Roberts_BW';
            app.RobertsButton = uiradiobutton(app.ExtraButtonGroup);
            app.RobertsButton.Text = 'Roberts';
            app.ApplyButton = uibutton(app.ExtraTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.BackButton_4 = uibutton(app.ExtraTab, 'push');
            app.BackButton_4.ButtonPushedFcn = createCallbackFcn(app, @BackButton_4Pushed, true);
            app.BackButton_4.Text = 'Back';
            app.SkipButton_Extra = uibutton(app.ExtraTab, 'push');
            app.SkipButton_Extra.ButtonPushedFcn = createCallbackFcn(app, @SkipButton_ExtraPushed, true);
            app.SkipButton_Extra.Text = 'Skip';
            app.ContinueButton = uibutton(app.ExtraTab, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Enable = 'off';
            app.ContinueButton.Text = 'Continue';
            app.UIExtra = uiaxes(app.ExtraTab);
            app.Slider = uislider(app.ExtraTab);
            app.Slider.Limits = [0 1];
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.Slider.Enable = 'off';
            app.Slider.Visible = 'off';
            app.Slider.Value = 0.5;
            app.OtsuButton = uibutton(app.ExtraTab, 'push');
            app.OtsuButton.ButtonPushedFcn = createCallbackFcn(app, @OtsuButtonPushed, true);
            app.OtsuButton.Enable = 'off';
            app.OtsuButton.Visible = 'off';
            app.OtsuButton.Text = 'Otsu';
            app.EditField = uieditfield(app.ExtraTab, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Limits = [0 1];
            app.EditField.ValueDisplayFormat = '%.2f';
            app.EditField.Editable = 'off';
            app.EditField.Enable = 'off';
            app.EditField.Visible = 'off';
            app.FacesTab = uitab(app.TabGroup);
            app.FacesTab.Title = 'Faces';
            app.UIFaces = uiaxes(app.FacesTab);
            app.DetectButton = uibutton(app.FacesTab, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            app.BackButton_5 = uibutton(app.FacesTab, 'push');
            app.BackButton_5.ButtonPushedFcn = createCallbackFcn(app, @BackButton_5Pushed, true);
            app.BackButton_5.Text = 'Back';
            app.NumberofFacesEditFieldLabel = uilabel(app.FacesTab);
            app.NumberofFacesEditFieldLabel.Text = 'Number of Faces';
            app.NumberofFacesEditField = uieditfield(app.FacesTab, 'numeric');
            app.NumberofFacesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofFacesEditFieldValueChanged, true);
            app.message = uitextarea(app.FacesTab);
            app.message.ValueChangedFcn = createCallbackFcn(app, @messageValueChanged, true);
            app.message.Editable = 'off';
            app.message.Value = {'YOU NEED FRIENDS. You do not get to see the power of Steganography.'};
            app.Proceed = uibutton(app.FacesTab, 'push');
            app.Proceed.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.Proceed.Enable = 'off';
            app.Proceed.Text = 'Proceed';
            app.WinTab = uitab(app.TabGroup);
            app.WinTab.Title = 'Win';
            app.Message = uilabel(app.WinTab);
            app.Message.Text = 'Are you ready to hide secret message in any picture?';
            app.HitMeButton = uibutton(app.WinTab, 'push');
            app.HitMeButton.ButtonPushedFcn = createCallbackFcn(app, @HitMeButtonPushed, true);
            app.HitMeButton.Enable = 'off';
            app.HitMeButton.Text = 'Hit Me';
            app.Switch = uiswitch(app.WinTab, 'slider');
            app.Switch.Items = {'No', 'Yes'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'No';
        end
    end

    methods (Access = public)

        % Construct app
        function app = page1beta

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end